classdef ProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        LogsheetPathEditFieldLabel     matlab.ui.control.Label
        LogsheetPathEditField          matlab.ui.control.EditField
        ProjectNameEditFieldLabel      matlab.ui.control.Label
        ProjectNameEditField           matlab.ui.control.EditField
        ProjectPathEditFieldLabel      matlab.ui.control.Label
        ProjectPathEditField           matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        RedoCheckBox                   matlab.ui.control.CheckBox
        AddDataTypesCheckBox           matlab.ui.control.CheckBox
        UpdateMetadataOnlyCheckBox     matlab.ui.control.CheckBox
        ReloadExistingDataCheckBox     matlab.ui.control.CheckBox
        OpenimportSettingsButton       matlab.ui.control.Button
        ProcessTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        FunctionNamesLabel             matlab.ui.control.Label
        FunctionNamesTextArea          matlab.ui.control.TextArea
        FunctionGroupEditFieldLabel    matlab.ui.control.Label
        FunctionGroupEditField         matlab.ui.control.NumericEditField
        specifyTrialsButton            matlab.ui.control.Button
        AddFunctionstoGroupSaveButton  matlab.ui.control.Button
        ClickoutsideboxwhendoneLabel   matlab.ui.control.Label
        LoadFunctionGroupFromFileButton  matlab.ui.control.Button
        NewProcessingFunctionTrialLevelButton  matlab.ui.control.Button
        NewProcessingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewProcessingFunctionProjectLevelButton  matlab.ui.control.Button
        RunTab                         matlab.ui.container.Tab
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Fcn1Label                      matlab.ui.control.Label
        Fcn2Label                      matlab.ui.control.Label
        Fcn3Label                      matlab.ui.control.Label
        Fcn4Label                      matlab.ui.control.Label
        Fcn5Label                      matlab.ui.control.Label
        Fcn6Label                      matlab.ui.control.Label
        Fcn7Label                      matlab.ui.control.Label
        Fcn8Label                      matlab.ui.control.Label
        Fcn9Label                      matlab.ui.control.Label
        Fcn10Label                     matlab.ui.control.Label
        FcnNamesLabel                  matlab.ui.control.Label
        RedoFcnLabel                   matlab.ui.control.Label
        CheckBox_1                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_10                    matlab.ui.control.CheckBox
        RunGroupButton                 matlab.ui.control.Button
        SaveGroupButton                matlab.ui.control.Button
        RunAllButton                   matlab.ui.control.Button
        SaveAllButton                  matlab.ui.control.Button
        PlotTab                        matlab.ui.container.Tab
        AllPlottingFunctionNamesTextAreaLabel  matlab.ui.control.Label
        AllPlottingFunctionNamesTextArea  matlab.ui.control.TextArea
        SelectaFunctionDropDownLabel   matlab.ui.control.Label
        SelectaFunctionDropDown        matlab.ui.control.DropDown
        MultipleTrialsCheckBox         matlab.ui.control.CheckBox
        AxisLabelsCheckBox             matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SaveFigCheckBox                matlab.ui.control.CheckBox
        SavePNGCheckBox                matlab.ui.control.CheckBox
        SavetransparentPNGCheckBox     matlab.ui.control.CheckBox
        XLimitsEditFieldLabel          matlab.ui.control.Label
        XLimitsEditField               matlab.ui.control.EditField
        YLimitsEditFieldLabel          matlab.ui.control.Label
        YLimitsEditField               matlab.ui.control.EditField
        ZLimitsEditFieldLabel          matlab.ui.control.Label
        ZLimitsEditField               matlab.ui.control.EditField
        CreatePlottingFunctionButton   matlab.ui.control.Button
        RootSaveFolderEditFieldLabel   matlab.ui.control.Label
        RootSaveFolderEditField        matlab.ui.control.EditField
        CurrentSaveSubfolderEditFieldLabel  matlab.ui.control.Label
        CurrentSaveSubfolderEditField  matlab.ui.control.EditField
        StatisticsTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CurrGroupFcnNames % Function names for one individual set of functions.
        FcnSet % Number identifying one set of functions
        allFcnNames % Names of all functions to run (across all groups)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RedoCheckBox.Value=0; % Initialize Redo to be unchecked.
            evalin('base','projectStruct.Info.Flags.Redo=0;');
            app.AddDataTypesCheckBox.Value=0; % AddDataTypes begins unchecked.
            evalin('base','projectStruct.Info.Flags.AddDataTypes=0;');
            app.UpdateMetadataOnlyCheckBox.Value=0; % UpdateMetadata begins unchecked.
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=0;');
            app.ReloadExistingDataCheckBox.Value=0; % ReloadExistingData begins unchecked.
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=0;');
            app.FcnSet=0; % Initialize group number to be 0.
        end

        % Value changed function: LogsheetPathEditField
        function LogsheetPathEditFieldValueChanged(app, event)
            value = app.LogsheetPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.LogsheetPath=tempName;');
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectName=tempName;');
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            value = app.ProjectPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectPath=tempName;');
            cd(value); % Set current directory to project path.
        end

        % Value changed function: RedoCheckBox
        function RedoCheckBoxValueChanged(app, event)
            value = app.RedoCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Redo=tempCheckbox;');
        end

        % Value changed function: AddDataTypesCheckBox
        function AddDataTypesCheckBoxValueChanged(app, event)
            value = app.AddDataTypesCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.AddDataTypes=tempCheckbox;');
        end

        % Value changed function: UpdateMetadataOnlyCheckBox
        function UpdateMetadataOnlyCheckBoxValueChanged(app, event)
            value = app.UpdateMetadataOnlyCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=tempCheckbox;');
        end

        % Value changed function: ReloadExistingDataCheckBox
        function ReloadExistingDataCheckBoxValueChanged(app, event)
            value = app.ReloadExistingDataCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=tempCheckbox');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            evalin('base','projectStruct=importProject(projectStruct);'); % Run the import.
        end

        % Button pushed function: specifyTrialsButton
        function specifyTrialsButtonPushed(app, event)
            evalin('base','cd(projectStruct.Info.ProjectPath)'); % Set the current directory to the project folder.
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            evalin('base',"currName=['specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '.m'];");
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    assignin('base','tempName',listing(i).name); % Assign the current file name to base.
                    if evalin('base','isequal(tempName,currName)') % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                evalin('base',char("copyfile('specifyTrials_Template.m',currName);")); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [inclStruct]=specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '(logsheetPath,projectPath,projectName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
            cd(currCD);
        end

        % Value changed function: FunctionNamesTextArea
        function FunctionNamesTextAreaValueChanged(app, event)
            value = app.FunctionNamesTextArea.Value;
            assignin('base','fcnNames',value);
            app.CurrGroupFcnNames=value;
            disp('Functions in Current Group Changed To:');
            app.CurrGroupFcnNames
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Are You Sure You Want to Close?','Confirm Close',...
                'Icon','warning');
            if isequal(selection,'OK')
                delete(app);
            end
        end

        % Button pushed function: AddFunctionstoGroupSaveButton
        function AddFunctionstoGroupSaveButtonPushed(app, event)
            if app.FcnSet==0
                disp('NEED TO SET A GROUP NUMBER FIRST!');
                beep;
                return;
            end
            app.allFcnNames{app.FcnSet}=app.CurrGroupFcnNames; % Store the current set of functions into the master list. Will overwrite prior entries.
            disp(['Functions Added to Group ' num2str(app.FcnSet) ':']);
            app.CurrGroupFcnNames % Display the function names in this group added to the master list
        end

        % Value changed function: FunctionGroupEditField
        function FunctionGroupEditFieldValueChanged(app, event)
            value = app.FunctionGroupEditField.Value;
            assignin('base','fcnSet',value); % Assign the function group number specified to base.
            app.FcnSet=value; % Store the function group number.
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab; % Called when switching from Setup to Run
            if isequal(selectedTab.Title,'Run') % In the Run tab
                if app.FcnSet==0
                    groupList={'NULL'};
                    app.allFcnNames=groupList;
                else
                    groupList=cell(length(app.allFcnNames),1); % Initialize list of groups.
                    for i=1:length(groupList)
                        groupList{i,1}=['Group ' num2str(i)];
                    end
                end
                app.DropDown.Items=groupList; % Set dropdown list according to the number of groups.
                % Set the checkboxes to be set to match the current group.
                currDropdownVal = app.DropDown.Value;
                if ~isequal(currDropdownVal,'NULL')
                    groupNum=str2double(currDropdownVal(end)); % Group number
                else
                    groupNum=1;
                end
                currGroupNames=app.allFcnNames{groupNum}; % List of current function names.
                for i=1:10 % Iterate over all labels.
                    app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                    if i<=length(currGroupNames) && ~isequal(currGroupNames,'NULL') % Check if this group exists.
                        app.(['Fcn' num2str(i) 'Label']).Visible='On';
                        app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                        app.(['CheckBox_' num2str(i)]).Visible='On';
                    else
                        app.(['CheckBox_' num2str(i)]).Visible='Off';
                        app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                    end
                end
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            groupNum=str2double(value(end)); % Group number
            currGroupNames=app.allFcnNames{groupNum}; % List of current function names.
            for i=1:10 % Iterate over all labels.
                app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                if i<=length(currGroupNames) % Check if this group exists.
                    app.(['Fcn' num2str(i) 'Label']).Visible='On';
                    app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                    app.(['CheckBox_' num2str(i)]).Visible='On';
                else
                    app.(['CheckBox_' num2str(i)]).Visible='Off';
                    app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                end
            end
            
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            % Need to associate the value of this checkbox with the corresponding function, and store it to be run later.
        end

        % Button pushed function: OpenimportSettingsButton
        function OpenimportSettingsButtonPushed(app, event)
            if evalin('base',"exist(['importSettings' projectStruct.Info.ProjectName]);")==2
                evalin('base',"edit(['importSettings' projectStruct.Info.ProjectName]);"); % Open file if it already exists.
            else
                % Make & format the file if it does not already exist (based on template).
                
            end
        end

        % Button pushed function: 
        % NewProcessingFunctionTrialLevelButton
        function NewProcessingFunctionTrialLevelButtonPushed(app, event)
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                evalin('base',"copyfile('trialTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.                
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionSubjectLevelButton
        function NewProcessingFunctionSubjectLevelButtonPushed(app, event)
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                evalin('base',"copyfile('subjectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.                
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.LogsheetPathEditFieldLabel = uilabel(app.ImportTab);
            app.LogsheetPathEditFieldLabel.Text = 'Logsheet Path';
            app.LogsheetPathEditField = uieditfield(app.ImportTab, 'text');
            app.LogsheetPathEditField.ValueChangedFcn = createCallbackFcn(app, @LogsheetPathEditFieldValueChanged, true);
            app.LogsheetPathEditField.Value = 'Full Path to Logsheet Excel File';
            app.ProjectNameEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectNameEditField.Value = 'Project Name';
            app.ProjectPathEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.ProjectPathEditField.Value = 'Full Path to Project Processing Folder';
            app.RunButton = uibutton(app.ImportTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RedoCheckBox = uicheckbox(app.ImportTab);
            app.RedoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedoCheckBoxValueChanged, true);
            app.RedoCheckBox.Text = 'Redo';
            app.AddDataTypesCheckBox = uicheckbox(app.ImportTab);
            app.AddDataTypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddDataTypesCheckBoxValueChanged, true);
            app.AddDataTypesCheckBox.Text = 'Add Data Types';
            app.UpdateMetadataOnlyCheckBox = uicheckbox(app.ImportTab);
            app.UpdateMetadataOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateMetadataOnlyCheckBoxValueChanged, true);
            app.UpdateMetadataOnlyCheckBox.Text = 'Update Metadata Only';
            app.ReloadExistingDataCheckBox = uicheckbox(app.ImportTab);
            app.ReloadExistingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReloadExistingDataCheckBoxValueChanged, true);
            app.ReloadExistingDataCheckBox.Text = 'Reload Existing Data';
            app.OpenimportSettingsButton = uibutton(app.ImportTab, 'push');
            app.OpenimportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimportSettingsButtonPushed, true);
            app.OpenimportSettingsButton.Text = 'Open importSettings';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.TabGroup2 = uitabgroup(app.ProcessTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = 'Group functions together based on what order they''re run and which set of trials they''re run on';
            app.FunctionNamesLabel = uilabel(app.SetupTab);
            app.FunctionNamesLabel.Text = 'Function Names';
            app.FunctionNamesTextArea = uitextarea(app.SetupTab);
            app.FunctionNamesTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionNamesTextAreaValueChanged, true);
            app.FunctionGroupEditFieldLabel = uilabel(app.SetupTab);
            app.FunctionGroupEditFieldLabel.Text = 'Function Group #';
            app.FunctionGroupEditField = uieditfield(app.SetupTab, 'numeric');
            app.FunctionGroupEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionGroupEditFieldValueChanged, true);
            app.specifyTrialsButton = uibutton(app.SetupTab, 'push');
            app.specifyTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @specifyTrialsButtonPushed, true);
            app.specifyTrialsButton.Text = 'specifyTrials';
            app.AddFunctionstoGroupSaveButton = uibutton(app.SetupTab, 'push');
            app.AddFunctionstoGroupSaveButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunctionstoGroupSaveButtonPushed, true);
            app.AddFunctionstoGroupSaveButton.Text = 'Add Functions to Group & Save';
            app.ClickoutsideboxwhendoneLabel = uilabel(app.SetupTab);
            app.ClickoutsideboxwhendoneLabel.Text = 'Click outside box when done.';
            app.LoadFunctionGroupFromFileButton = uibutton(app.SetupTab, 'push');
            app.LoadFunctionGroupFromFileButton.Text = 'Load Function Group From File';
            app.NewProcessingFunctionTrialLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionTrialLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionTrialLevelButtonPushed, true);
            app.NewProcessingFunctionTrialLevelButton.Text = 'New Processing Function: Trial Level';
            app.NewProcessingFunctionSubjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionSubjectLevelButtonPushed, true);
            app.NewProcessingFunctionSubjectLevelButton.Text = 'New Processing Function: Subject Level';
            app.NewProcessingFunctionProjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionProjectLevelButton.Text = 'New Processing Function: Project Level';
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.DropDownLabel = uilabel(app.RunTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RunTab);
            app.DropDown.Items = {'Group 1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Group 1';
            app.Fcn1Label = uilabel(app.RunTab);
            app.Fcn1Label.Text = 'Fcn1';
            app.Fcn2Label = uilabel(app.RunTab);
            app.Fcn2Label.Text = 'Fcn2';
            app.Fcn3Label = uilabel(app.RunTab);
            app.Fcn3Label.Text = 'Fcn3';
            app.Fcn4Label = uilabel(app.RunTab);
            app.Fcn4Label.Text = 'Fcn4';
            app.Fcn5Label = uilabel(app.RunTab);
            app.Fcn5Label.Text = 'Fcn5';
            app.Fcn6Label = uilabel(app.RunTab);
            app.Fcn6Label.Text = 'Fcn6';
            app.Fcn7Label = uilabel(app.RunTab);
            app.Fcn7Label.Text = 'Fcn7';
            app.Fcn8Label = uilabel(app.RunTab);
            app.Fcn8Label.Text = 'Fcn8';
            app.Fcn9Label = uilabel(app.RunTab);
            app.Fcn9Label.Text = 'Fcn9';
            app.Fcn10Label = uilabel(app.RunTab);
            app.Fcn10Label.Text = 'Fcn10';
            app.FcnNamesLabel = uilabel(app.RunTab);
            app.FcnNamesLabel.Text = 'FcnNames';
            app.RedoFcnLabel = uilabel(app.RunTab);
            app.RedoFcnLabel.Text = 'Redo Fcn';
            app.CheckBox_1 = uicheckbox(app.RunTab);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.RunTab);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.RunTab);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.RunTab);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.RunTab);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.RunTab);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.RunTab);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.RunTab);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.RunTab);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.RunTab);
            app.CheckBox_10.Text = '10';
            app.RunGroupButton = uibutton(app.RunTab, 'push');
            app.RunGroupButton.Text = 'Run Group';
            app.SaveGroupButton = uibutton(app.RunTab, 'push');
            app.SaveGroupButton.Text = 'Save Group';
            app.RunAllButton = uibutton(app.RunTab, 'push');
            app.RunAllButton.Text = 'Run All';
            app.SaveAllButton = uibutton(app.RunTab, 'push');
            app.SaveAllButton.Text = 'Save All';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.AllPlottingFunctionNamesTextAreaLabel = uilabel(app.PlotTab);
            app.AllPlottingFunctionNamesTextAreaLabel.Text = 'All Plotting Function Names';
            app.AllPlottingFunctionNamesTextArea = uitextarea(app.PlotTab);
            app.SelectaFunctionDropDownLabel = uilabel(app.PlotTab);
            app.SelectaFunctionDropDownLabel.Text = 'Select a Function';
            app.SelectaFunctionDropDown = uidropdown(app.PlotTab);
            app.MultipleTrialsCheckBox = uicheckbox(app.PlotTab);
            app.MultipleTrialsCheckBox.Text = 'Multiple Trials';
            app.AxisLabelsCheckBox = uicheckbox(app.PlotTab);
            app.AxisLabelsCheckBox.Text = 'Axis Labels';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.Text = 'Plot';
            app.SaveFigCheckBox = uicheckbox(app.PlotTab);
            app.SaveFigCheckBox.Text = 'Save Fig';
            app.SavePNGCheckBox = uicheckbox(app.PlotTab);
            app.SavePNGCheckBox.Text = 'Save PNG';
            app.SavetransparentPNGCheckBox = uicheckbox(app.PlotTab);
            app.SavetransparentPNGCheckBox.Text = 'Save transparent PNG';
            app.XLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.XLimitsEditField.Value = 'all, OR subject, OR condition';
            app.YLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.YLimitsEditField.Value = 'OR subjectCondition, OR each';
            app.ZLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.ZLimitsEditFieldLabel.Text = 'Z Limits';
            app.ZLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.CreatePlottingFunctionButton = uibutton(app.PlotTab, 'push');
            app.CreatePlottingFunctionButton.Text = 'Create Plotting Function';
            app.RootSaveFolderEditFieldLabel = uilabel(app.PlotTab);
            app.RootSaveFolderEditFieldLabel.Text = 'Root Save Folder';
            app.RootSaveFolderEditField = uieditfield(app.PlotTab, 'text');
            app.RootSaveFolderEditField.Value = 'Full Folder Path';
            app.CurrentSaveSubfolderEditFieldLabel = uilabel(app.PlotTab);
            app.CurrentSaveSubfolderEditFieldLabel.Text = 'Current Save Subfolder';
            app.CurrentSaveSubfolderEditField = uieditfield(app.PlotTab, 'text');
            app.CurrentSaveSubfolderEditField.Value = 'Folder Name';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end